classdef Hw2_M11003330 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        FileMenu       matlab.ui.container.Menu
        OpenMenu       matlab.ui.container.Menu
        SaveAsMenu     matlab.ui.container.Menu
        ExitMenu       matlab.ui.container.Menu
        FilterMenu     matlab.ui.container.Menu
        NoneMenu       matlab.ui.container.Menu
        NegativeMenu   matlab.ui.container.Menu
        LRFlipMenu     matlab.ui.container.Menu
        DarkerMenu     matlab.ui.container.Menu
        EdgeMenu       matlab.ui.container.Menu
        BlurringMenu   matlab.ui.container.Menu
        NoiseMenu      matlab.ui.container.Menu
        WaterMarkMenu  matlab.ui.container.Menu
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        DeleteButton   matlab.ui.control.StateButton
        PlayButton     matlab.ui.control.StateButton
        OriginalImg    matlab.ui.control.Image
        TimeLineSld    matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        RightPanel     matlab.ui.container.Panel
        FilterLab      matlab.ui.control.Label
        ProcessedImg   matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        m_Video  % 視訊物件
        m_Frame  % 當下的畫面
        m_DeleteTimeRecord  % 按下刪除按鈕紀錄時間
        m_DeleteTimeIdx  % 存放將刪除的時間片段
        m_Delete_list  % 輸出時要刪除的時間列表
        m_FrameNum = 1  % 當下的幀數

        m_FilterFlag = 0  % 影像處理選項
        m_DeleteFlag = 0  % 現在影像是否要刪除
        m_DeleteTimes = 0  % 使用刪除鍵的次數
        m_WaterMarkName = {'M11003330'}  % 浮水印字樣
    end
    
    
    methods (Access = private)
        
        %% Filter 影像處理
        function results = m_ProcessMethod(app)
            switch app.m_FilterFlag  % 濾鏡選項
                case 0  % 原始
                    Process = (app.m_Frame);
                    app.FilterLab.Text = 'None';
                case 1  % 反白
                    Process = imcomplement(app.m_Frame);
                    app.FilterLab.Text = 'Negative';
                case 2  % 左右翻轉
                    Process = fliplr(app.m_Frame);
                    app.FilterLab.Text = 'LR Flip';
                case 3  % 變暗
                    Process = app.m_Frame - 64;
                    app.FilterLab.Text = 'Darker';
                case 4  % 凸顯邊緣
                    Process = 255*(repmat(uint8(edge(rgb2gray(app.m_Frame), "canny")), [1 1 3]));
                    app.FilterLab.Text = 'Edge';
                case 5  % 平均模糊
                    Process = imfilter(app.m_Frame, (fspecial('average', [15 15])));
                    app.FilterLab.Text = 'Blurring';
                case 6  % 高斯雜訊
                    Process = imnoise(app.m_Frame, "gaussian", 0, 0.2);
                    app.FilterLab.Text = 'Noise';
            end

            switch app.m_DeleteFlag  % 確認是否使用刪除
                case 0  % 沒有要刪除，直接輸出
                    results = Process;
                case 1  % 有要刪除，畫面調黑，加上刪除字樣
                    results = insertText((Process-128), [60 60], 'Delete', 'FontSize', 120,'BoxColor', 'y');
            end
        end

        %% 整理刪除時間
        function m_DeleteRecorder(app)
            app.m_DeleteTimeRecord(:, 2) = app.m_Video.CurrentTime;  % 紀錄刪除鍵使用時間
            app.m_DeleteTimes = app.m_DeleteTimes + 1;  % 紀錄刪除鍵使用次數
            app.m_DeleteTimeIdx(app.m_DeleteTimes, :) = sort(app.m_DeleteTimeRecord, 2);  % 整理單次刪除鍵時間先後
            SortedRowsIdx = sortrows(app.m_DeleteTimeIdx, 1);  % 初步排列個時間區間
            app.m_Delete_list = SortedRowsIdx(1, :);
            
            % 檢查刪除時間是否重複，若重疊就取代為新的區間，若重複就忽略
            for check_n = 2:size(SortedRowsIdx, 1)
                if app.m_Delete_list(end, 2) < SortedRowsIdx(check_n, 1)
                    app.m_Delete_list(end+1, :) = SortedRowsIdx(check_n, :);
                else
                    app.m_Delete_list(end, 2) = max(app.m_Delete_list(end, 2), SortedRowsIdx(check_n, 2));
                end
            end
            clc
            disp(app.m_Delete_list)  % 輸出最終整理出的時間列表
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clear; close all
            app.OriginalImg.ScaleMethod = 'stretch';
            app.ProcessedImg.ScaleMethod = 'stretch';
            app.TimeLineSld.Enable = "off";
            app.m_FilterFlag = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                app.PlayButton.Icon = 'pause.jpg';
                read(app.m_Video, app.m_FrameNum);
                
                % 播放影片，以及 播放中 按暫停之操作
                while hasFrame(app.m_Video)
                    if app.PlayButton.Value == 0  % 按暫停時，取消運行
                        break;
                    end
                    app.m_Frame = readFrame(app.m_Video);  % 讀取影片
                    pause(1/app.m_Video.FrameRate)
                    app.m_FrameNum = floor(double(app.m_Video.CurrentTime*app.m_Video.FrameRate));  % 校正當下幀數
                    app.TimeLineSld.Value = floor(app.m_Video.CurrentTime);  % 校正時間軸數值
                    app.Label.Text = num2str(app.m_Video.CurrentTime,'%.2f');  % 校正時間軸數字
                    app.OriginalImg.ImageSource = app.m_Frame;  % 顯示原圖
                    app.ProcessedImg.ImageSource = m_ProcessMethod(app);  % 顯示處理後的圖
                end

                % 影片 按下暫停時，重設播放按鈕
                app.PlayButton.Icon = 'down.jpg';
                app.PlayButton.Value = 0;
                
                % 影片 播放結束時，校正時間相關參數，及最後顯示畫面
                app.m_FrameNum = floor(double(app.m_Video.CurrentTime*app.m_Video.FrameRate));  % 校正當下幀數
                app.m_Frame = read(app.m_Video, (app.m_FrameNum-1));  % 讀取最後的影像
                app.OriginalImg.ImageSource = app.m_Frame;  % 顯示到Image圖框
                app.ProcessedImg.ImageSource = m_ProcessMethod(app);
            else
                app.m_FrameNum = floor(double(app.m_Video.CurrentTime*app.m_Video.FrameRate));  % 紀錄影片暫停時的幀數
                app.PlayButton.Icon = 'down.jpg';
            end

            % 設定影片 播放結束時，可以重複播放
            if app.m_Video.CurrentTime + (1.1/app.m_Video.FrameRate) >= app.m_Video.Duration  % 影片播放到結尾(設定為倒數一幀)
                % 影片 播放結束時，取消運行中的刪除按鈕
                if app.DeleteButton.Value == 1
                    app.DeleteButton.Value = 0;
                    DeleteButtonValueChanged(app, event)
                end
                app.m_FrameNum = 1;  % 將影片時間幀數重置為1
                app.m_Video.CurrentTime = 0;  % 將影片時間重置為0
                app.TimeLineSld.Value = 0;  % 將時間軸重置為0
                app.Label.Text = num2str(app.m_Video.CurrentTime,'%.2f');  % 將時間軸數字為0
            end
        end

        % Value changed function: TimeLineSld
        function TimeLineSldValueChanged(app, event)
            % 互相回饋影片時間與時間軸時間，並校正播放幀數
            app.m_Video.CurrentTime = app.TimeLineSld.Value;
            app.TimeLineSld.Value = app.m_Video.CurrentTime;
            app.m_FrameNum = floor(double(app.m_Video.CurrentTime*app.m_Video.FrameRate));  % 同步紀錄的幀數

            % 防止SLD拉到0時播放幀數為0
            if app.m_FrameNum == 0
                app.m_FrameNum = 1;
            end

            app.Label.Text = num2str(app.m_Video.CurrentTime,'%.2f');  % 輸出影片的播放時間
            app.m_Frame = read(app.m_Video, (app.m_FrameNum));  % 讀取目標幀數的影像
            app.OriginalImg.ImageSource = app.m_Frame;  % 顯示原圖
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);  % 顯示處理後的圖
        end

        % Value changed function: DeleteButton
        function DeleteButtonValueChanged(app, event)
            pushDown = app.DeleteButton.Value;
            if pushDown
                app.m_DeleteFlag = 1;  % 設定刪除狀態
                app.m_DeleteTimeRecord(:, 1) = app.m_Video.CurrentTime;  % 紀錄刪除起始時間
                app.DeleteButton.BackgroundColor = [0.68, 0.68, 0.68];    % 按鈕調整

                % 預防讀取幀數為0
                if app.m_FrameNum == 1
                    app.m_FrameNum = 2;
                end

                app.m_Frame = read(app.m_Video, (app.m_FrameNum-1));  % 讀取 要刪除當下的畫面，並播放
                app.OriginalImg.ImageSource = app.m_Frame;
                app.ProcessedImg.ImageSource = m_ProcessMethod(app);
            else
                app.m_DeleteFlag = 0;  % 設定刪除狀態
                app.m_DeleteRecorder();  % 紀錄刪除結束時間，並進行處理
                app.DeleteButton.BackgroundColor = [0.96, 0.96, 0.96];  % 按鈕調整
                
                % 預防讀取幀數為0
                if app.m_FrameNum == 1
                    app.m_FrameNum = 2;
                end

                app.m_Frame = read(app.m_Video, (app.m_FrameNum-1));  % 讀取  取消刪除當下的畫面，並播放
                app.OriginalImg.ImageSource = app.m_Frame;
                app.ProcessedImg.ImageSource = m_ProcessMethod(app);
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % 讀取視訊路徑檔名
            [fName, pName] = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            % 建立視訊物件
            if ischar(fName) == 0 || ischar(pName) == 0
                return;  % 若沒有選擇影片，就此結束事件
            end
            app.m_Video = VideoReader([pName fName]);
            app.m_Frame = read(app.m_Video, app.m_FrameNum);  % 讀取第一幀的影像
            app.OriginalImg.ImageSource = app.m_Frame;  % 顯示原圖
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);  % 顯示處理後的圖
            app.PlayButton.Icon = 'down.jpg';

            % 讀取輸入影片資料並用來設定Silder
            app.TimeLineSld.Limits = [0, floor(app.m_Video.Duration)];  % 設定SLD尺寸
            app.TimeLineSld.MajorTicks = round(linspace(0, app.m_Video.Duration, 11));  % 設定SLD Ticks大小
            app.TimeLineSld.Value = 0;  % SLD數值初始化

            figure(app.UIFigure)
            
            app.FilterMenu.Enable = "on";
            app.WaterMarkMenu.Enable = "on";
            app.SaveAsMenu.Enable = "on";
            app.PlayButton.Enable = "on";
            app.TimeLineSld.Enable = "on";
            app.DeleteButton.Enable = "on";
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            if app.DeleteButton.Value == 1  % 若要使用SAVE AS，取消運行中的刪除按鈕
                app.DeleteButton.Value = 0;
                DeleteButtonValueChanged(app, event)  % 強制記錄當下時間為刪除的最後時間點
            end

            [filename, pathname] = uiputfile({'*.mp4'},'Save a video file');
            if ischar(filename) == 0 || ischar(pathname) == 0
                return;  % 若沒有建立影片，取消執行
            end

            VideoConverter = VideoWriter([pathname filename],'MPEG-4');  % 建立待寫入的mp4視訊物件
            VideoConverter.Quality = 90;  % default quality = 75
            open(VideoConverter)  % 開啟視訊物件

            uiWaitBar = waitbar(0, "Converting.....");

            app.m_Frame = read(app.m_Video, 1);  % 處理第一幀
            Filtered = m_ProcessMethod(app);
            ImgOut = insertText(Filtered, [60 60], app.m_WaterMarkName{1}, 'FontSize', 80, 'BoxColor', 'y');
            writeVideo(VideoConverter, ImgOut);  % 將第一幀寫入輸出視訊

            while hasFrame(app.m_Video)  % 如果還有畫面，就寫入影像
                app.m_Frame = readFrame(app.m_Video);  % 讀取下一幀

                isDelete = false;
                for i = 1:size(app.m_Delete_list, 1)
                    if app.m_Video.CurrentTime >= app.m_Delete_list(i, 1) && app.m_Video.CurrentTime <= app.m_Delete_list(i, 2)
                        isDelete = true;  % 如果在時間區間內，標記這一幀要排除掉
                        break;  % 跳出循環
                    end
                end
                if isDelete
                    continue;  % 如果這一幀要排除，不進行寫入，直接進入下一迭代
                end

                Filtered = m_ProcessMethod(app);
                ImgOut = insertText(Filtered, [60 60], app.m_WaterMarkName{1}, 'FontSize', 80, 'BoxColor', 'y');
                writeVideo(VideoConverter, ImgOut);  % 將這一幀寫入輸出影片視訊

                ConvertProgress = (app.m_Video.CurrentTime / app.m_Video.Duration);  % 設定WaitBar進度值
                waitbar(ConvertProgress, uiWaitBar);  % 設計WaitBar
            end
            
            app.m_Delete_list = [];  % 重設要刪除的時間片段
            close(VideoConverter);  % 關閉輸出視訊物件  
            close(uiWaitBar);  % 關閉時間條
            clc; clear
        end

        % Menu selected function: WaterMarkMenu
        function WaterMarkMenuSelected(app, event)
            app.m_WaterMarkName = inputdlg({'WaterMark'}, 'WaterMark', 1, {'M11003330'});  % 使用視窗輸入浮水印內容
        end

        % Menu selected function: NoneMenu
        function NoneMenuSelected(app, event)
            app.m_FilterFlag = 0;  % 設定濾鏡CASE編號
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.m_FilterFlag = 1;  % 設定濾鏡CASE編號
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.m_FilterFlag = 2;  % 設定濾鏡CASE編號
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.m_FilterFlag = 3;  % 設定濾鏡CASE編號
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);
        end

        % Menu selected function: EdgeMenu
        function EdgeMenuSelected(app, event)
            app.m_FilterFlag = 4;  % 設定濾鏡CASE編號
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);
        end

        % Menu selected function: BlurringMenu
        function BlurringMenuSelected(app, event)
            app.m_FilterFlag = 5;  % 設定濾鏡CASE編號
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);
        end

        % Menu selected function: NoiseMenu
        function NoiseMenuSelected(app, event)
            app.m_FilterFlag = 6;  % 設定濾鏡CASE編號
            app.ProcessedImg.ImageSource = m_ProcessMethod(app);
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            %quit()
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Enable = 'off';
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.FilterMenu = uimenu(app.UIFigure);
            app.FilterMenu.Enable = 'off';
            app.FilterMenu.Text = 'Filter';
            app.NoneMenu = uimenu(app.FilterMenu);
            app.NoneMenu.MenuSelectedFcn = createCallbackFcn(app, @NoneMenuSelected, true);
            app.NoneMenu.Text = 'None';
            app.NegativeMenu = uimenu(app.FilterMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.FilterMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.FilterMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.EdgeMenu = uimenu(app.FilterMenu);
            app.EdgeMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeMenuSelected, true);
            app.EdgeMenu.Text = 'Edge';
            app.BlurringMenu = uimenu(app.FilterMenu);
            app.BlurringMenu.MenuSelectedFcn = createCallbackFcn(app, @BlurringMenuSelected, true);
            app.BlurringMenu.Text = 'Blurring';
            app.NoiseMenu = uimenu(app.FilterMenu);
            app.NoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @NoiseMenuSelected, true);
            app.NoiseMenu.Text = 'Noise';
            app.WaterMarkMenu = uimenu(app.UIFigure);
            app.WaterMarkMenu.MenuSelectedFcn = createCallbackFcn(app, @WaterMarkMenuSelected, true);
            app.WaterMarkMenu.Enable = 'off';
            app.WaterMarkMenu.Text = 'Water Mark';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = ' ==';
            app.TimeLineSld = uislider(app.LeftPanel);
            app.TimeLineSld.Limits = [0 10];
            app.TimeLineSld.MajorTicks = [];
            app.TimeLineSld.ValueChangedFcn = createCallbackFcn(app, @TimeLineSldValueChanged, true);
            app.TimeLineSld.MinorTicks = [];
            app.TimeLineSld.Enable = 'off';
            app.OriginalImg = uiimage(app.LeftPanel);
            app.PlayButton = uibutton(app.LeftPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = 'pause.jpg';
            app.PlayButton.Text = '';
            app.DeleteButton = uibutton(app.LeftPanel, 'state');
            app.DeleteButton.ValueChangedFcn = createCallbackFcn(app, @DeleteButtonValueChanged, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.RightPanel = uipanel(app.GridLayout);
            app.ProcessedImg = uiimage(app.RightPanel);
            app.FilterLab = uilabel(app.RightPanel);
            app.FilterLab.Text = 'Label_Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hw2_M11003330

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end